classdef FilterPlotting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        F0Slider_3Label      matlab.ui.control.Label
        F0Slider_3           matlab.ui.control.Slider
        QFacSliderLabel      matlab.ui.control.Label
        QFacSlider           matlab.ui.control.Slider
        BoostSliderLabel     matlab.ui.control.Label
        BoostSlider          matlab.ui.control.Slider
        PlayButton           matlab.ui.control.Button
        kDropDown_2          matlab.ui.control.DropDown
        DurationSliderLabel  matlab.ui.control.Label
        DurationSlider       matlab.ui.control.Slider
        F01SliderLabel       matlab.ui.control.Label
        F01Slider            matlab.ui.control.Slider
        Boost1SliderLabel    matlab.ui.control.Label
        Boost1Slider         matlab.ui.control.Slider
        QFac1SliderLabel     matlab.ui.control.Label
        QFac1Slider          matlab.ui.control.Slider
        F02SliderLabel       matlab.ui.control.Label
        F02Slider            matlab.ui.control.Slider
        Boost2SliderLabel    matlab.ui.control.Label
        Boost2Slider         matlab.ui.control.Slider
        QFac2SliderLabel     matlab.ui.control.Label
        QFac2Slider          matlab.ui.control.Slider
        F03SliderLabel       matlab.ui.control.Label
        F03Slider            matlab.ui.control.Slider
        Boost3SliderLabel    matlab.ui.control.Label
        Boost3Slider         matlab.ui.control.Slider
        QFac3SliderLabel     matlab.ui.control.Label
        QFac3Slider          matlab.ui.control.Slider
        F04SliderLabel       matlab.ui.control.Label
        F04Slider            matlab.ui.control.Slider
        Boost4SliderLabel    matlab.ui.control.Label
        Boost4Slider         matlab.ui.control.Slider
        QFac4SliderLabel     matlab.ui.control.Label
        QFac4Slider          matlab.ui.control.Slider
        ResetAllButton       matlab.ui.control.Button
        ResetButton_2        matlab.ui.control.Button
        ResetButton_3        matlab.ui.control.Button
        ResetButton_4        matlab.ui.control.Button
        ResetButton_5        matlab.ui.control.Button
        ResetButton_6        matlab.ui.control.Button
        RightPanel           matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        a
        b
        H
        FileString = "";
        AudioFs
    end


    methods (Access = public)
        
        function FilterPlot(app, ~)
            %calculates and plots the frequency response of a bell filter given
            %sampling frequency, centre frequency and boost/cut
            Fs = 96000;
            F0 = app.F0Slider_3.Value;
            Qfac = app.QFacSlider.Value;
            boost = app.BoostSlider.Value;
            
            [b0, a0] = FilterCalc(app, Fs, F0, Qfac, boost);         
            
            [Hd, Fd] = freqz(b0, a0, 2^15, Fs); %get frequency response of discrete BZT transfer function
            
            plot(app.UIAxes, Fd, 20*log10(abs(Hd)), 'b') %Plot discrete BZT magnitude response
            

        end
    end
    
    methods (Access = public)
        
        function [b, a] = FilterCalc(~, Fs, F0, Qfac, boost)
            
            %calculate filter coefficients 
            G = 10^(boost/20); %calculate gain
            k = 3*((G -1)/(G + 1)); %calculate k
                    
            Wo =  2*pi*F0; %calculate centre frequency in Radians per second
            Wo2 = Wo^2; %centre frequency in Rads/s squared
            
            HSnum = [1 ((3+k)*(Wo/Qfac)) (Wo2)]; %calculate continuous transfer function numerator
            HSden = [1 ((3-k)*(Wo/Qfac)) (Wo2)]; %calculate continuous transfer function denominator
                 
            [b, a] = bilinear(HSnum, HSden, Fs, F0); %calc BZT coefficients for each iteration of the continuous TF            


        end
    end
    
    methods (Access = public)
        
        function UpdatePlot(app, ~)
            
            Fs = 96000;
            F0 = app.F0Slider_3.Value;
            F01 = app.F01Slider.Value;
            F02 = app.F02Slider.Value;
            F03 = app.F03Slider.Value;
            F04 = app.F04Slider.Value;
            Qfac = app.QFacSlider.Value;
            Qfac1 = app.QFac1Slider.Value;
            Qfac2 = app.QFac2Slider.Value;
            Qfac3 = app.QFac3Slider.Value;
            Qfac4 = app.QFac4Slider.Value;
            boost = app.BoostSlider.Value;
            boost1 = app.Boost1Slider.Value;
            boost2 = app.Boost2Slider.Value;
            boost3 = app.Boost3Slider.Value;
            boost4 = app.Boost4Slider.Value;
            
            [b0, a0] = FilterCalc(app, Fs, F0, Qfac, boost);
            
            [b1, a1] = FilterCalc(app, Fs, F01, Qfac1, boost1);
            
            [b2, a2] = FilterCalc(app, Fs, F02, Qfac2, boost2);
            
            [b3, a3] = FilterCalc(app, Fs, F03, Qfac3, boost3);
            
            [b4, a4] = FilterCalc(app, Fs, F04, Qfac4, boost4);
            
            H0 = dfilt.df2t(b0,a0);
            H1 = dfilt.df2t(b1,a1);
            H2 = dfilt.df2t(b2,a2);
            H3 = dfilt.df2t(b3,a3);
            H4 = dfilt.df2t(b4,a4);
            
            app.H = dfilt.cascade(H0, H1, H2, H3, H4);
            
            [h, w] = freqz(app.H, 2^15, Fs);
            
            plot(app.UIAxes, w, 20*log10(abs(h)), 'b');
        
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %calculates and plots the frequency response of a bell filter given
            %sampling frequency, centre frequency and boost/cut
            Fs = 96000;
            F0 = 8000;
            Qfac = 2;
            boost = 0;
            
            %calculate filter coefficients 
            G = 10^(boost/20); %calculate gain
            k = 3*((G -1)/(G + 1)); %calculate k
                    
            Wo =  2*pi*F0; %calculate centre frequency in Radians per second
            Wo2 = Wo^2; %centre frequency in Rads/s squared
            
            HSnum = [1 ((3+k)*(Wo/Qfac)) (Wo2)]; %calculate continuous transfer function numerator
            HSden = [1 ((3-k)*(Wo/Qfac)) (Wo2)]; %calculate continuous transfer function denominator
                 
            [Hznum, HZden] = bilinear(HSnum, HSden, Fs, F0); %calc BZT coefficients for each iteration of the continuous TF            
            
            [Hd, Fd] = freqz(Hznum, HZden, 2^15, Fs); %get frequency response of discrete BZT transfer function
            
            plot(app.UIAxes, Fd, 20*log10(abs(Hd)), 'b') %Plot discrete BZT magnitude response
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {825, 825};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {635, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: F0Slider_3
        function F0Slider_3ValueChanged(app, event)
          
            UpdatePlot(app, event);
            disp(app.F0Slider_3.Value);
        end

        % Value changing function: F0Slider_3
        function F0Slider_3ValueChanging(app, event)
           
            UpdatePlot(app, event);
            
        end

        % Value changing function: QFacSlider
        function QFacSliderValueChanging(app, event)
            
            UpdatePlot(app, event);
            
        end

        % Value changing function: BoostSlider
        function BoostSliderValueChanging(app, event)
           
            UpdatePlot(app, event);
            
        end

        % Value changed function: BoostSlider
        function BoostSliderValueChanged(app, event)
            
                UpdatePlot(app, event);
            
        end

        % Value changed function: QFacSlider
        function QFacSliderValueChanged(app, event)
            
                 UpdatePlot(app, event);
            
        end

        % Value changed function: kDropDown_2
        function kDropDown_2ValueChanged(app, event)

            Selection = app.kDropDown_2.Value;
            disp(Selection);
            
            switch Selection
                
                case '1'
                    
                    disp("nothing selected");    
                    
                case '2'
                    
                    disp(Selection);
                    app.FileString = '63378__casualsamples__bajos-22.wav';
                    app.AudioFs = 48000;     
                    
                case '3'
                    
                    disp(Selection);
                    app.FileString = '419028__realdavidfloat__kick-snare-loops-reverb-variations-120bpm.wav';
                    app.AudioFs = 44100;
                    
                case '4'
                    
                    disp(Selection);
                    app.FileString = '448213__florianreichelt__white-noise.wav';
                    app.AudioFs = 48000;
                    
                case '5'
                    
                    disp(Selection);
                    app.FileString = '440737__lamont-roberts__sweep001.wav';
                    app.AudioFs = 44100;
                 otherwise
                  
                     disp("Clip selection error");
            end
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
            AudioData = audioread(app.FileString);
            filteredsignal = filter(app.H,AudioData);
            player = audioplayer(filteredsignal, app.AudioFs);
            play(player);
            pause(app.DurationSlider.Value);
            stop(player);
            
        end

        % Callback function
        function StopButtonPushed(app, event)
            stop(player);
        end

        % Drop down opening function: kDropDown_2
        function kDropDown_2Opening(app, event)
            
        end

        % Value changed function: DurationSlider
        function DurationSliderValueChanged(app, event)
            Value = app.DurationSlider.Value;
            disp("Duration:");
            disp(Value);
        end

        % Value changed function: Boost1Slider
        function Boost1SliderValueChanged(app, event)
            
            UpdatePlot(app, event);
            
        end

        % Value changed function: QFac1Slider
        function QFac1SliderValueChanged(app, event)
           
            UpdatePlot(app, event);
        end

        % Value changing function: QFac1Slider
        function QFac1SliderValueChanging(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changing function: Boost1Slider
        function Boost1SliderValueChanging(app, event)
            
            UpdatePlot(app, event);
            
        end

        % Value changed function: F01Slider
        function F01SliderValueChanged(app, event)
            
            UpdatePlot(app, event);
            
        end

        % Value changing function: F01Slider
        function F01SliderValueChanging(app, event)
            
            UpdatePlot(app, event);
            
        end

        % Value changed function: F02Slider
        function F02SliderValueChanged(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changed function: F03Slider
        function F03SliderValueChanged(app, event)
           UpdatePlot(app, event);
            
        end

        % Value changing function: F02Slider
        function F02SliderValueChanging(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changing function: F03Slider
        function F03SliderValueChanging(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changed function: F04Slider
        function F04SliderValueChanged(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changed function: Boost2Slider
        function Boost2SliderValueChanged(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changing function: Boost2Slider
        function Boost2SliderValueChanging(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changed function: Boost3Slider
        function Boost3SliderValueChanged(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changing function: Boost3Slider
        function Boost3SliderValueChanging(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changed function: Boost4Slider
        function Boost4SliderValueChanged(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changing function: Boost4Slider
        function Boost4SliderValueChanging(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changed function: QFac2Slider
        function QFac2SliderValueChanged(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changing function: QFac2Slider
        function QFac2SliderValueChanging(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changing function: QFac3Slider
        function QFac3SliderValueChanging(app, event)
            UpdatePlot(app, event);
        end

        % Value changed function: QFac3Slider
        function QFac3SliderValueChanged(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changed function: QFac4Slider
        function QFac4SliderValueChanged(app, event)
            UpdatePlot(app, event);
            
        end

        % Value changing function: QFac4Slider
        function QFac4SliderValueChanging(app, event)
            UpdatePlot(app, event);
            
        end

        % Callback function
        function ResetButtonValueChanged(app, event)
            app.BoostSlider.Value = 0;
            BoostSliderValueChanged(app,event);
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            
            app.BoostSlider.Value = 0;
            BoostSliderValueChanged(app,event);
            
            app.Boost1Slider.Value = 0;
            Boost1SliderValueChanged(app,event);
            
            app.Boost2Slider.Value = 0;
            Boost2SliderValueChanged(app,event);
            
            app.Boost3Slider.Value = 0;
            Boost3SliderValueChanged(app,event);
            
            app.Boost4Slider.Value = 0;
            Boost4SliderValueChanged(app,event);
            
            
            app.QFacSlider.Value = 1;
            QFacSliderValueChanged(app,event);
            
            app.QFac1Slider.Value = 1;
            QFac1SliderValueChanged(app,event);
            
            app.QFac2Slider.Value = 1;
            QFac2SliderValueChanged(app,event);
            
            app.QFac3Slider.Value = 1;
            QFac3SliderValueChanged(app,event);
            
            app.QFac4Slider.Value = 1;
            QFac4SliderValueChanged(app,event);
            
            
            app.F0Slider_3.Value = 8000;
            F0Slider_3ValueChanged(app,event);
            
            app.F01Slider.Value = 8000;
            F01SliderValueChanged(app,event);
            
            app.F02Slider.Value = 8000;
            F02SliderValueChanged(app,event);
            
            app.F03Slider.Value = 8000;
            F03SliderValueChanged(app,event);
            
            app.F04Slider.Value = 8000;
            F04SliderValueChanged(app,event);
            
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.F0Slider_3.Value = 8000;
            F0Slider_3ValueChanged(app,event);
            
            app.BoostSlider.Value = 0;
            BoostSliderValueChanged(app,event);
            
            app.QFacSlider.Value = 1;
            QFacSliderValueChanged(app,event);
           
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.F01Slider.Value = 8000;
            F0Slider_3ValueChanged(app,event);
            
            app.Boost1Slider.Value = 0;
            BoostSliderValueChanged(app,event);
            
            app.QFac1Slider.Value = 1;
            QFacSliderValueChanged(app,event);
            
            
        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
            app.F02Slider.Value = 8000;
            F0Slider_3ValueChanged(app,event);
            
            app.Boost2Slider.Value = 0;
            BoostSliderValueChanged(app,event);
            
            app.QFac2Slider.Value = 1;
            QFacSliderValueChanged(app,event);
            
        end

        % Button pushed function: ResetButton_5
        function ResetButton_5Pushed(app, event)
            app.F03Slider.Value = 8000;
            F0Slider_3ValueChanged(app,event);
            
            app.Boost3Slider.Value = 0;
            BoostSliderValueChanged(app,event);
            
            app.QFac3Slider.Value = 1;
            QFacSliderValueChanged(app,event);
        end

        % Button pushed function: ResetButton_6
        function ResetButton_6Pushed(app, event)
            app.F04Slider.Value = 8000;
            F0Slider_3ValueChanged(app,event);
            
            app.Boost4Slider.Value = 0;
            BoostSliderValueChanged(app,event);
            
            app.QFac4Slider.Value = 1;
            QFacSliderValueChanged(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.F0Slider_3Label = uilabel(app.LeftPanel);
            app.F0Slider_3Label.Text = 'F0';
            app.F0Slider_3 = uislider(app.LeftPanel);
            app.F0Slider_3.Limits = [1 16000];
            app.F0Slider_3.MajorTicks = [1 4000 8000 12000 16000];
            app.F0Slider_3.ValueChangedFcn = createCallbackFcn(app, @F0Slider_3ValueChanged, true);
            app.F0Slider_3.ValueChangingFcn = createCallbackFcn(app, @F0Slider_3ValueChanging, true);
            app.F0Slider_3.Value = 8000;
            app.QFacSliderLabel = uilabel(app.LeftPanel);
            app.QFacSliderLabel.Text = 'QFac';
            app.QFacSlider = uislider(app.LeftPanel);
            app.QFacSlider.Limits = [1 30];
            app.QFacSlider.MajorTicks = [1 5 10 15 20 25 30];
            app.QFacSlider.MajorTickLabels = {'1', '5', '10', '15', '20', '25', '30'};
            app.QFacSlider.ValueChangedFcn = createCallbackFcn(app, @QFacSliderValueChanged, true);
            app.QFacSlider.ValueChangingFcn = createCallbackFcn(app, @QFacSliderValueChanging, true);
            app.QFacSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.QFacSlider.Value = 2;
            app.BoostSliderLabel = uilabel(app.LeftPanel);
            app.BoostSliderLabel.Text = 'Boost';
            app.BoostSlider = uislider(app.LeftPanel);
            app.BoostSlider.Limits = [-20 20];
            app.BoostSlider.ValueChangedFcn = createCallbackFcn(app, @BoostSliderValueChanged, true);
            app.BoostSlider.ValueChangingFcn = createCallbackFcn(app, @BoostSliderValueChanging, true);
            app.BoostSlider.MinorTicks = [-20 -16 -12 -8 -4 0 4 8 12 16 20];
            app.PlayButton = uibutton(app.LeftPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.kDropDown_2 = uidropdown(app.LeftPanel);
            app.kDropDown_2.Items = {'Select a sound clip', 'Bass Guitar', 'Kick Snare', 'White noise', 'Frequency Sweep (loud)'};
            app.kDropDown_2.ItemsData = {'1', '2', '3', '4', '5'};
            app.kDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @kDropDown_2Opening, true);
            app.kDropDown_2.ValueChangedFcn = createCallbackFcn(app, @kDropDown_2ValueChanged, true);
            app.kDropDown_2.Value = '1';
            app.DurationSliderLabel = uilabel(app.LeftPanel);
            app.DurationSliderLabel.Text = 'Duration';
            app.DurationSlider = uislider(app.LeftPanel);
            app.DurationSlider.Limits = [0 30];
            app.DurationSlider.ValueChangedFcn = createCallbackFcn(app, @DurationSliderValueChanged, true);
            app.F01SliderLabel = uilabel(app.LeftPanel);
            app.F01SliderLabel.Text = 'F0(1)';
            app.F01Slider = uislider(app.LeftPanel);
            app.F01Slider.Limits = [1 16000];
            app.F01Slider.MajorTicks = [1 4000 8000 12000 16000];
            app.F01Slider.ValueChangedFcn = createCallbackFcn(app, @F01SliderValueChanged, true);
            app.F01Slider.ValueChangingFcn = createCallbackFcn(app, @F01SliderValueChanging, true);
            app.F01Slider.Value = 8000;
            app.Boost1SliderLabel = uilabel(app.LeftPanel);
            app.Boost1SliderLabel.Text = 'Boost(1)';
            app.Boost1Slider = uislider(app.LeftPanel);
            app.Boost1Slider.Limits = [-20 20];
            app.Boost1Slider.ValueChangedFcn = createCallbackFcn(app, @Boost1SliderValueChanged, true);
            app.Boost1Slider.ValueChangingFcn = createCallbackFcn(app, @Boost1SliderValueChanging, true);
            app.Boost1Slider.MinorTicks = [-20 -16 -12 -8 -4 0 4 8 12 16 20];
            app.QFac1SliderLabel = uilabel(app.LeftPanel);
            app.QFac1SliderLabel.Text = 'QFac(1)';
            app.QFac1Slider = uislider(app.LeftPanel);
            app.QFac1Slider.Limits = [1 30];
            app.QFac1Slider.MajorTicks = [1 5 10 15 20 25 30];
            app.QFac1Slider.ValueChangedFcn = createCallbackFcn(app, @QFac1SliderValueChanged, true);
            app.QFac1Slider.ValueChangingFcn = createCallbackFcn(app, @QFac1SliderValueChanging, true);
            app.QFac1Slider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.QFac1Slider.Value = 2;
            app.F02SliderLabel = uilabel(app.LeftPanel);
            app.F02SliderLabel.Text = 'F0(2)';
            app.F02Slider = uislider(app.LeftPanel);
            app.F02Slider.Limits = [1 16000];
            app.F02Slider.MajorTicks = [1 4000 8000 12000 16000];
            app.F02Slider.ValueChangedFcn = createCallbackFcn(app, @F02SliderValueChanged, true);
            app.F02Slider.ValueChangingFcn = createCallbackFcn(app, @F02SliderValueChanging, true);
            app.F02Slider.Value = 8000;
            app.Boost2SliderLabel = uilabel(app.LeftPanel);
            app.Boost2SliderLabel.Text = 'Boost(2)';
            app.Boost2Slider = uislider(app.LeftPanel);
            app.Boost2Slider.Limits = [-20 20];
            app.Boost2Slider.ValueChangedFcn = createCallbackFcn(app, @Boost2SliderValueChanged, true);
            app.Boost2Slider.ValueChangingFcn = createCallbackFcn(app, @Boost2SliderValueChanging, true);
            app.Boost2Slider.MinorTicks = [-20 -16 -12 -8 -4 0 4 8 12 16 20];
            app.QFac2SliderLabel = uilabel(app.LeftPanel);
            app.QFac2SliderLabel.Text = 'QFac(2)';
            app.QFac2Slider = uislider(app.LeftPanel);
            app.QFac2Slider.Limits = [1 30];
            app.QFac2Slider.MajorTicks = [1 5 10 15 20 25 30];
            app.QFac2Slider.ValueChangedFcn = createCallbackFcn(app, @QFac2SliderValueChanged, true);
            app.QFac2Slider.ValueChangingFcn = createCallbackFcn(app, @QFac2SliderValueChanging, true);
            app.QFac2Slider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.QFac2Slider.Value = 2;
            app.F03SliderLabel = uilabel(app.LeftPanel);
            app.F03SliderLabel.Text = 'F0(3)';
            app.F03Slider = uislider(app.LeftPanel);
            app.F03Slider.Limits = [1 16000];
            app.F03Slider.MajorTicks = [1 4000 8000 12000 16000];
            app.F03Slider.ValueChangedFcn = createCallbackFcn(app, @F03SliderValueChanged, true);
            app.F03Slider.ValueChangingFcn = createCallbackFcn(app, @F03SliderValueChanging, true);
            app.F03Slider.Value = 8000;
            app.Boost3SliderLabel = uilabel(app.LeftPanel);
            app.Boost3SliderLabel.Text = 'Boost(3)';
            app.Boost3Slider = uislider(app.LeftPanel);
            app.Boost3Slider.Limits = [-20 20];
            app.Boost3Slider.ValueChangedFcn = createCallbackFcn(app, @Boost3SliderValueChanged, true);
            app.Boost3Slider.ValueChangingFcn = createCallbackFcn(app, @Boost3SliderValueChanging, true);
            app.Boost3Slider.MinorTicks = [-20 -16 -12 -8 -4 0 4 8 12 16 20];
            app.QFac3SliderLabel = uilabel(app.LeftPanel);
            app.QFac3SliderLabel.Text = 'QFac(3)';
            app.QFac3Slider = uislider(app.LeftPanel);
            app.QFac3Slider.Limits = [1 30];
            app.QFac3Slider.MajorTicks = [1 5 10 15 20 25 30];
            app.QFac3Slider.ValueChangedFcn = createCallbackFcn(app, @QFac3SliderValueChanged, true);
            app.QFac3Slider.ValueChangingFcn = createCallbackFcn(app, @QFac3SliderValueChanging, true);
            app.QFac3Slider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.QFac3Slider.Value = 2;
            app.F04SliderLabel = uilabel(app.LeftPanel);
            app.F04SliderLabel.Text = 'F0(4)';
            app.F04Slider = uislider(app.LeftPanel);
            app.F04Slider.Limits = [1 16000];
            app.F04Slider.MajorTicks = [1 4000 8000 12000 16000];
            app.F04Slider.ValueChangedFcn = createCallbackFcn(app, @F04SliderValueChanged, true);
            app.F04Slider.Value = 8000;
            app.Boost4SliderLabel = uilabel(app.LeftPanel);
            app.Boost4SliderLabel.Text = 'Boost(4)';
            app.Boost4Slider = uislider(app.LeftPanel);
            app.Boost4Slider.Limits = [-20 20];
            app.Boost4Slider.ValueChangedFcn = createCallbackFcn(app, @Boost4SliderValueChanged, true);
            app.Boost4Slider.ValueChangingFcn = createCallbackFcn(app, @Boost4SliderValueChanging, true);
            app.Boost4Slider.MinorTicks = [-20 -16 -12 -8 -4 0 4 8 12 16 20];
            app.QFac4SliderLabel = uilabel(app.LeftPanel);
            app.QFac4SliderLabel.Text = 'QFac(4)';
            app.QFac4Slider = uislider(app.LeftPanel);
            app.QFac4Slider.Limits = [1 30];
            app.QFac4Slider.MajorTicks = [1 5 10 15 20 25 30];
            app.QFac4Slider.ValueChangedFcn = createCallbackFcn(app, @QFac4SliderValueChanged, true);
            app.QFac4Slider.ValueChangingFcn = createCallbackFcn(app, @QFac4SliderValueChanging, true);
            app.QFac4Slider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.QFac4Slider.Value = 2;
            app.ResetAllButton = uibutton(app.LeftPanel, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.ResetButton_2 = uibutton(app.LeftPanel, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.ResetButton_3 = uibutton(app.LeftPanel, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.ResetButton_4 = uibutton(app.LeftPanel, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Text = 'Reset';
            app.ResetButton_5 = uibutton(app.LeftPanel, 'push');
            app.ResetButton_5.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_5Pushed, true);
            app.ResetButton_5.Text = 'Reset';
            app.ResetButton_6 = uibutton(app.LeftPanel, 'push');
            app.ResetButton_6.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_6Pushed, true);
            app.ResetButton_6.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Frequency Response ')
            xlabel(app.UIAxes, 'Frequency 10KHz')
            ylabel(app.UIAxes, 'Magnitude dBs')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 50000];
            app.UIAxes.YLim = [-40 40];
            app.UIAxes.XTick = [0 5000 10000 15000 20000 25000 30000 35000 40000 45000 50000];
            app.UIAxes.XTickLabel = {'0'; '0.5'; '1'; '1.5'; '2'; '2.5'; '3'; '3.5'; '4'; '4.5'; '5'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilterPlotting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end