classdef coefficients < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        a3EditField                 matlab.ui.control.NumericEditField
        a3EditFieldLabel            matlab.ui.control.Label
        a2EditField                 matlab.ui.control.NumericEditField
        a2EditFieldLabel            matlab.ui.control.Label
        a1EditField                 matlab.ui.control.NumericEditField
        a1EditFieldLabel            matlab.ui.control.Label
        b3EditField                 matlab.ui.control.NumericEditField
        b3EditFieldLabel            matlab.ui.control.Label
        b2EditField                 matlab.ui.control.NumericEditField
        b2EditFieldLabel            matlab.ui.control.Label
        b1EditField                 matlab.ui.control.NumericEditField
        b1EditFieldLabel            matlab.ui.control.Label
        QEditField_2                matlab.ui.control.NumericEditField
        ComputeButton               matlab.ui.control.Button
        QEditField_2Label           matlab.ui.control.Label
        SamplingFreqEditField       matlab.ui.control.NumericEditField
        SamplingFreqEditFieldLabel  matlab.ui.control.Label
        BoostEditField              matlab.ui.control.NumericEditField
        BoostEditFieldLabel         matlab.ui.control.Label
        CentreFreqEditField         matlab.ui.control.NumericEditField
        CentreFreqEditFieldLabel    matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        
        function [Fs,Fo,boost,Q] = getDataFromUI(app)
            Fs = app.SamplingFreqEditField.Value;
            Fo = app.CentreFreqEditField.Value;
            boost = app.BoostEditField.Value;
            Q = app.QEditField_2.Value;
        end
        
        function results = sanityCheck(app,Fs,Fo,boost,Q)
            if Fs <= 0 || Fo <= 0 || boost <-20 || boost > 20 || Q <= 0
                results = -1;
            else
                results = 1;
            end
        end
        
        function [result] = updatePlot(app,b1,a1,Fs)
            [Hd,Fd] = freqz(b1,a1,2^15,Fs);
            plot(app.UIAxes,Fd,20*log10(abs(Hd)),'r:');
            result = 1;
        end
    end

    methods (Access = private)  
        function results = updateUI(app,b1,a1)
            app.b1EditField.Value = b1(1);
            app.b2EditField.Value = b1(2);
            app.b3EditField.Value = b1(3);
            app.a1EditField.Value = a1(1);
            app.a2EditField.Value = a1(2);
            app.a3EditField.Value = a1(3);
            results = 1;
        end
        % Callback function: CentreFreqEditField, ComputeButton
        function [b1,a1] = CalculateBellFilter(app,Fs,Fo,boost,Q)
            G = 10^(boost/20);
            k = 3*(G-1)/(G+1);
            Wo = 2*pi*Fo;
            B = [1 (3+k)*Wo/Q Wo^2]; % Specify analogue transfer func.
            A = [1 (3-k)*Wo/Q Wo^2];
            [b1, a1] = bilinear(B, A, Fs,Fo); % calculate BZT filter coefficient
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CentreFreqEditField, ComputeButton
        function ComputeButtonPushed(app, event)
           [Fs,Fo,boost,Q] = app.getDataFromUI();
           [isDataValid] = app.sanityCheck(Fs,Fo,boost,Q);
           if isDataValid == -1
               return
           end
           [b1,a1] = app.CalculateBellFilter(Fs,Fo,boost,Q);
           [result] = app.updateUI(b1,a1);
           [resultOfPlot] = app.updatePlot(b1,a1,Fs);
        end

        % Value changed function: SamplingFreqEditField
        function SamplingFreqEditFieldValueChanged(app, event)
            value = app.SamplingFreqEditField.Value;
            if value <= 0
                app.SamplingFreqEditField.Value = 1000;
                errordlg("Sampling frequency can't be less than 0.");
            end

        end

        % Value changed function: BoostEditField
        function BoostEditFieldValueChanged(app, event)
            value = app.BoostEditField.Value;
            if value < -20 || value > 20
                app.BoostEditField.Value = 0;
                errordlg("Boost factor can't be less than -20 or more than 20");
            end
        end

        % Callback function
        function CentreFreqEditFieldValueChanged(app, event)
            value = app.CentreFreqEditField.Value;
            if value <= 0
                app.CentreFreqEditField.Value = 125;
                errordlg("Centre frequency can't be less than 0.");
            end
        end

        % Value changed function: QEditField_2
        function QEditField_2ValueChanged(app, event)
            value = app.QEditField_2.Value;
             if value <= 0
                app.QEditField_2.Value = 125;
                errordlg("Q can't be less than 0 or 0");
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Frequency response')
            xlabel(app.UIAxes, 'Frequency ( Hz )')
            ylabel(app.UIAxes, 'Magnitude(dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 4000 8000 12000 16000];
            app.UIAxes.XTickLabel = {'0'; '4000'; '8000'; '12000'; '16000'};
            app.UIAxes.YTick = [-20 -15 -10 -5 0 5 10 15 20];
            app.UIAxes.YTickLabel = {'-20'; '-15'; '-10'; '-5'; '0'; '5'; '10'; '15'; '20'};
            app.CentreFreqEditFieldLabel = uilabel(app.GridLayout);
            app.CentreFreqEditFieldLabel.Text = 'Centre Freq';
            app.CentreFreqEditField = uieditfield(app.GridLayout, 'numeric');
            app.CentreFreqEditField.ValueChangedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.BoostEditFieldLabel = uilabel(app.GridLayout);
            app.BoostEditFieldLabel.Text = 'Boost';
            app.BoostEditField = uieditfield(app.GridLayout, 'numeric');
            app.BoostEditField.ValueChangedFcn = createCallbackFcn(app, @BoostEditFieldValueChanged, true);
            app.SamplingFreqEditFieldLabel = uilabel(app.GridLayout);
            app.SamplingFreqEditFieldLabel.Text = 'Sampling Freq';
            app.SamplingFreqEditField = uieditfield(app.GridLayout, 'numeric');
            app.SamplingFreqEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFreqEditFieldValueChanged, true);
            app.QEditField_2Label = uilabel(app.GridLayout);
            app.QEditField_2Label.Text = 'Q';
            app.ComputeButton = uibutton(app.GridLayout, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.QEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.QEditField_2.ValueChangedFcn = createCallbackFcn(app, @QEditField_2ValueChanged, true);
            app.b1EditFieldLabel = uilabel(app.GridLayout);
            app.b1EditFieldLabel.Text = 'b1';
            app.b1EditField = uieditfield(app.GridLayout, 'numeric');
            app.b1EditField.Editable = 'off';
            app.b2EditFieldLabel = uilabel(app.GridLayout);
            app.b2EditFieldLabel.Text = 'b2';
            app.b2EditField = uieditfield(app.GridLayout, 'numeric');
            app.b2EditField.Editable = 'off';
            app.b3EditFieldLabel = uilabel(app.GridLayout);
            app.b3EditFieldLabel.Text = 'b3';
            app.b3EditField = uieditfield(app.GridLayout, 'numeric');
            app.b3EditField.Editable = 'off';
            app.a1EditFieldLabel = uilabel(app.GridLayout);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.GridLayout, 'numeric');
            app.a1EditField.Editable = 'off';
            app.a2EditFieldLabel = uilabel(app.GridLayout);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.GridLayout, 'numeric');
            app.a2EditField.Editable = 'off';
            app.a3EditFieldLabel = uilabel(app.GridLayout);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.GridLayout, 'numeric');
            app.a3EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coefficients

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end