classdef coefficients < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        COMDropDown                   matlab.ui.control.DropDown
        COMDropDownLabel              matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        ExportButton                  matlab.ui.control.Button
        a3EditField                   matlab.ui.control.NumericEditField
        a3EditFieldLabel              matlab.ui.control.Label
        a2EditField                   matlab.ui.control.NumericEditField
        a2EditFieldLabel              matlab.ui.control.Label
        a1EditField                   matlab.ui.control.NumericEditField
        a1EditFieldLabel              matlab.ui.control.Label
        b3EditField                   matlab.ui.control.NumericEditField
        b3EditFieldLabel              matlab.ui.control.Label
        b2EditField                   matlab.ui.control.NumericEditField
        b2EditFieldLabel              matlab.ui.control.Label
        b1EditField                   matlab.ui.control.NumericEditField
        b1EditFieldLabel              matlab.ui.control.Label
        QEditField_2                  matlab.ui.control.NumericEditField
        ComputeButton                 matlab.ui.control.Button
        QEditField_2Label             matlab.ui.control.Label
        SamplingFreqHzEditField       matlab.ui.control.NumericEditField
        SamplingFreqHzEditFieldLabel  matlab.ui.control.Label
        BoostEditField                matlab.ui.control.NumericEditField
        BoostEditFieldLabel           matlab.ui.control.Label
        CentreFreqHzEditField         matlab.ui.control.NumericEditField
        CentreFreqHzEditFieldLabel    matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

     properties (Access = private)
        Fs
        Fo

        BCoefficient
        ACoefficient

        b1
        b2
        b3
        a1
        a2
        a3
        serialPortCOM

        hasCOMPortSelected
        hasComputeButtonBeenPressed

     end  
    
    methods (Access = private)


        function [Fs,Fo,boost,Q] = getDataFromUI(app)
            Fs = app.SamplingFreqHzEditField.Value;
            Fo = app.CentreFreqHzEditField.Value;
            boost = app.BoostEditField.Value;
            Q = app.QEditField_2.Value;
        end
        
        function results = sanityCheck(app,Fs,Fo,boost,Q)
            if Fs <= 0 || Fo <= 0 || boost <-20 || boost > 20 || Q <= 0
                results = -1;
            else
                results = 1;
            end
        end
        
        function [result] = updatePlot(app,b1,a1,Fs)
            [Hd,Fd] = freqz(b1,a1,2^15,Fs);
            plot(app.UIAxes,Fd,20*log10(abs(Hd)),'r:');
            result = 1;
        end
    end

    methods (Access = private)  
        function results = updateUI(app,b1,a1)
            app.b1EditField.Value = b1(1);
            app.b2EditField.Value = b1(2);
            app.b3EditField.Value = b1(3);
            app.a1EditField.Value = a1(1);
            app.a2EditField.Value = a1(2);
            app.a3EditField.Value = a1(3);
            app.b1 = b1(1);
            app.b2 = b1(2);
            app.b3 = b1(3);
            app.a1 = a1(1);
            app.a2 = a1(2);
            app.a3 = a1(3);

            results = 1;
        end
        % Callback function: CentreFreqEditField, ComputeButton
        function [b1,a1] = CalculateBellFilter(app,Fs,Fo,boost,Q)
            G = 10^(boost/20);
            k = 3*(G-1)/(G+1);
            Wo = 2*pi*Fo;
            B = [1 (3+k)*Wo/Q Wo^2]; % Specify analogue transfer func.
            A = [1 (3-k)*Wo/Q Wo^2];
            [b1, a1] = bilinear(B, A, Fs,Fo); % calculate BZT filter coefficient
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CentreFreqHzEditField, ComputeButton
        function ComputeButtonPushed(app, event)
           [app.Fs,app.Fo,boost,Q] = app.getDataFromUI();
           [isDataValid] = app.sanityCheck(app.Fs,app.Fo,boost,Q);
           
           app.hasComputeButtonBeenPressed = 1;
           if isDataValid == -1
               return
           end
           [b1,a1] = app.CalculateBellFilter(app.Fs,app.Fo,boost,Q);
           app.BCoefficient = b1;
           app.ACoefficient = a1;
           app.PlotButton.Enable = 'on';
           [result] = app.updateUI(b1,a1);
           [resultOfPlot] = app.updatePlot(b1,a1,app.Fs);
           if app.hasCOMPortSelected == 1
                app.ExportButton.Enable = 'on';
           else
               return
           end
        end

        % Value changed function: SamplingFreqHzEditField
        function SamplingFreqHzEditFieldValueChanged(app, event)
            value = app.SamplingFreqHzEditField.Value;
            if value <= 0
                app.SamplingFreqHzEditField.Value = 1000;
                errordlg("Sampling frequency can't be less than 0.");
            end

        end

        % Callback function
        function CentreFreqEditFieldValueChanged(app, event)
            value = app.CentreFreqHzEditField.Value;
            if value <= 0
                app.CentreFreqHzEditField.Value = 125;
                errordlg("Centre frequency can't be less than 0.");
            end
        end

        % Value changed function: QEditField_2
        function QEditField_2ValueChanged(app, event)
            value = app.QEditField_2.Value;
             if value <= 0
                app.QEditField_2.Value = 125;
                errordlg("Q can't be less than 0 or 0");
             end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
           c = clock;
           fileID = fopen("coefficientsExported"+c(1) + c(2)+c(3) + c(4) + c(5) + ".h" ,'w');
           fprintf(fileID,"//Coefficients exported\n");
           fprintf(fileID,"//Generated using 101-2 app\n");
           fprintf(fileID,"//ELEC347 - MMI - 2021\n\n");
           fprintf(fileID,"#define Fs " + app.Fs);
           fprintf(fileID,"\n");
           fprintf(fileID,"#define CentreFrequency " + app.Fo);
           fprintf(fileID,"\n\n");
           fprintf(fileID,"//A-Denominator coefficients" );
           fprintf(fileID,"\n");
           fprintf(fileID,"float a1 = " + app.a1 + ";");
           fprintf(fileID,"\n");
           fprintf(fileID,"float a2 = " + app.a2 + ";");
           fprintf(fileID,"\n");
           fprintf(fileID,"float a3 = " + app.a3 + ";");
           fprintf(fileID,"\n");
           fprintf(fileID,"//B-Nominator coefficients");
           fprintf(fileID,"\n");
           fprintf(fileID,"float b1 = " + app.b1 + ";");
           fprintf(fileID,"\n");
           fprintf(fileID,"float b2 = " + app.b2 + ";");
           fprintf(fileID,"\n");
           fprintf(fileID,"float b3 = " + app.b3 + ";");
           fprintf(fileID,"\n");
           fprintf(fileID,"\n");
           msgbox("Coefficients saved as:" + "coefficientsExported"+c(1) + c(2)+c(3) + c(4) + c(5) + ".h" );
           

           if app.hasCOMPortSelected == 1
            dataToWrite="A," + app.a1 + "," + app.a2 + "," + app.a3 + "," + "B" +"," + app.b1 + "," + app.b2 + "," + app.b3 + "," + "STOP";
            writeline(app.serialPortCOM,dataToWrite);
            fprintf(fileID,"Written to serial:");
            fprintf(fileID,dataToWrite);
            msgbox(readline(app.serialPortCOM));
           else
               fclose(fileID);

           end

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            freqz(app.BCoefficient,app.ACoefficient,2^15,app.Fs);
        end

        % Drop down opening function: COMDropDown
        function COMDropDownOpening(app, event)
            freePorts = serialportlist("all");
            app.COMDropDown.Items = freePorts;
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            
            value = app.COMDropDown.Value;
            app.serialPortCOM = serialport(value,115200);
            app.COMDropDown.Items = app.serialPortCOM.Port;
            app.COMDropDown.Value = app.serialPortCOM.Port;
            app.hasCOMPortSelected = 1;
            if app.hasComputeButtonBeenPressed == 1
                app.ExportButton.Enable = 'on'
            end
            configureTerminator(app.serialPortCOM,"CR/LF");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Frequency response')
            xlabel(app.UIAxes, 'Frequency ( Hz )')
            ylabel(app.UIAxes, 'Magnitude(dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 4000 8000 12000 16000];
            app.UIAxes.XTickLabel = {'0'; '4000'; '8000'; '12000'; '16000'};
            app.UIAxes.YTick = [-20 -15 -10 -5 0 5 10 15 20];
            app.UIAxes.YTickLabel = {'-20'; '-15'; '-10'; '-5'; '0'; '5'; '10'; '15'; '20'};
            app.CentreFreqHzEditFieldLabel = uilabel(app.GridLayout);
            app.CentreFreqHzEditFieldLabel.Text = 'Centre Freq(Hz)';
            app.CentreFreqHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.CentreFreqHzEditField.Limits = [0 16000];
            app.CentreFreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.CentreFreqHzEditField.Value = 125;
            app.BoostEditFieldLabel = uilabel(app.GridLayout);
            app.BoostEditFieldLabel.Text = 'Boost';
            app.BoostEditField = uieditfield(app.GridLayout, 'numeric');
            app.BoostEditField.Limits = [-20 20];
            app.SamplingFreqHzEditFieldLabel = uilabel(app.GridLayout);
            app.SamplingFreqHzEditFieldLabel.Text = 'Sampling Freq(Hz)';
            app.SamplingFreqHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.SamplingFreqHzEditField.Limits = [0 97000];
            app.SamplingFreqHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFreqHzEditFieldValueChanged, true);
            app.SamplingFreqHzEditField.Value = 96000;
            app.QEditField_2Label = uilabel(app.GridLayout);
            app.QEditField_2Label.Text = 'Q';
            app.ComputeButton = uibutton(app.GridLayout, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.QEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.QEditField_2.Limits = [1 20];
            app.QEditField_2.ValueChangedFcn = createCallbackFcn(app, @QEditField_2ValueChanged, true);
            app.QEditField_2.Value = 1;
            app.b1EditFieldLabel = uilabel(app.GridLayout);
            app.b1EditFieldLabel.Text = 'b1';
            app.b1EditField = uieditfield(app.GridLayout, 'numeric');
            app.b1EditField.Editable = 'off';
            app.b2EditFieldLabel = uilabel(app.GridLayout);
            app.b2EditFieldLabel.Text = 'b2';
            app.b2EditField = uieditfield(app.GridLayout, 'numeric');
            app.b2EditField.Editable = 'off';
            app.b3EditFieldLabel = uilabel(app.GridLayout);
            app.b3EditFieldLabel.Text = 'b3';
            app.b3EditField = uieditfield(app.GridLayout, 'numeric');
            app.b3EditField.Editable = 'off';
            app.a1EditFieldLabel = uilabel(app.GridLayout);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.GridLayout, 'numeric');
            app.a1EditField.Editable = 'off';
            app.a2EditFieldLabel = uilabel(app.GridLayout);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.GridLayout, 'numeric');
            app.a2EditField.Editable = 'off';
            app.a3EditFieldLabel = uilabel(app.GridLayout);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.GridLayout, 'numeric');
            app.a3EditField.Editable = 'off';
            app.ExportButton = uibutton(app.GridLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.COMDropDownLabel = uilabel(app.GridLayout);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.GridLayout);
            app.COMDropDown.Items = {'N/A', ''};
            app.COMDropDown.DropDownOpeningFcn = createCallbackFcn(app, @COMDropDownOpening, true);
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'N/A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coefficients

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end