classdef prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        CurrentSongName          matlab.ui.control.Label
        SongLabel                matlab.ui.control.Label
        ResumeButton             matlab.ui.control.Button
        kHzLabel_3               matlab.ui.control.Label
        kHzLabel_2               matlab.ui.control.Label
        kHzLabel                 matlab.ui.control.Label
        HzLabel_2                matlab.ui.control.Label
        HzLabel                  matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        FifthStageSlider_16KHz   matlab.ui.control.Slider
        FourthStageSlider_8KHz   matlab.ui.control.Slider
        ThirdStageSlider_4KHz    matlab.ui.control.Slider
        SecondStageSlider_500Hz  matlab.ui.control.Slider
        FirstStageSlider_125HZ   matlab.ui.control.Slider
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename % Description
        path % Description
        player
        Fs = 96000;
        HZ_250 = 4000;
        Q = 1;
        AudioContent
        FilteredAudioContent
        CurrentSongSample
        STAGE_250HZ;


        STAGE_125HZ
        STAGE_500HZ
        STAGE_4KHZ
        STAGE_8KHZ
        STAGE_16KHZ


        STAGE1FILTERCOEF_A
        STAGE1FILTERCOEF_B

        STAGE2FILTERCOEF_A
        STAGE2FILTERCOEF_B

        STAGE3FILTERCOEF_A
        STAGE3FILTERCOEF_B

        STAGE4FILTERCOEF_A
        STAGE4FILTERCOEF_B

        STAGE5FILTERCOEF_A
        STAGE5FILTERCOEF_B


        FilteredAudioContentStageOne   
        FilteredAudioContentStageTwo
        FilteredAudioContentStageThree
        FilteredAudioContentStageFour
        FilteredAudioContentStageFive


    end
    
    methods (Access = private)
        
        function [b1,a1] = CalculateBellFilter(app,CentreFrequency,boost,Q)
            G = 10^(boost/20);
            k = 3*(G-1)/(G+1);
            Wo = 2*pi*CentreFrequency;
            B = [1 (3+k)*Wo/Q Wo^2]; % Specify analogue transfer func.
            A = [1 (3-k)*Wo/Q Wo^2];
            [b1, a1] = bilinear(B, A, app.Fs,CentreFrequency); % calculate BZT filter coefficient
        end
        
        function ParseAudioData(app)
            [app.AudioContent,app.Fs] = audioread(app.filename);
        end
        
        function LoadSong(app)
            [app.filename,app.path] = uigetfile({"*.wav";"*.mp3";}); 
            app.UpdateSongLabel();
             app.ParseAudioData();
        end
        
        function CreateAudioPlayer(app,StartingSample)
            %Creates an audioplayer object
            app.player = audioplayer(StartingSample,app.Fs); 
        end
        
        function ApplyFilterToData(app)
            [gain] = app.GetSlider250Hz();
            [b1,a1] = app.CalculateBellFilter(gain);
            app.FilteredAudioContent = filter(b1,a1,app.AudioContent);
        end
       
        
        function SliderChangeHandle(app,SliderID)
            switch SliderID
                case app.FirstStageSlider_125HZ.Tag
                    app.CurrentSongName.Text = "Slider 1 change"
                    app.UpdateOutputContent();
                case app.SecondStageSlider_500Hz.Tag
                    app.CurrentSongName.Text = "Slider 2 change"
                    app.UpdateOutputContent();
                case app.ThirdStageSlider_4KHz.Tag
                    app.CurrentSongName.Text = "Slider 3 change"
                    app.UpdateOutputContent();
                case app.FourthStageSlider_8KHz.Tag
                    app.CurrentSongName.Text = "Slider 4 change"
                    app.UpdateOutputContent();
                case app.FifthStageSlider_16KHz.Tag
                    app.CurrentSongName.Text = "Slider 5 change"
                    app.UpdateOutputContent();
            end
        end


        function FetchCurrentSample(app)
            app.CurrentSongSample = get(app.player,'CurrentSample');
        end


        function results = GeneralTransferFunction(app)
            
        end
        
        function UpdateSongLabel(app)
            app.CurrentSongName.Text = app.filename;
        end
        
        function UpdateMisc(app)
            app.ApplyFilterToData();
            pause(0.05);
            app.FetchCurrentSample();
            stop(app.player);
            %app.CreateAudioPlayer(app.CurrentSongSample);
            app.CreateAudioPlayer(app.FilteredAudioContent);
            play(app.player,app.CurrentSongSample);
        end
        
        function ComputeStageOne(app)
            gain = app.FirstStageSlider_125HZ.Value;
            [app.STAGE1FILTERCOEF_A,app.STAGE1FILTERCOEF_B] = app.CalculateBellFilter(125,gain,1);
        end
        
        function ComputeStageTwo(app)
            gain = app.SecondStageSlider_500Hz.Value;
            [app.STAGE2FILTERCOEF_A,app.STAGE2FILTERCOEF_B] = app.CalculateBellFilter(500,gain,1);
        end
        
        function ComputeStageThree(app)
              gain = app.ThirdStageSlider_4KHz.Value;
            [app.STAGE3FILTERCOEF_A,app.STAGE3FILTERCOEF_B] = app.CalculateBellFilter(4000,gain,1);
        end
        
        function ComputeStageFour(app)
            gain = app.FourthStageSlider_8KHz.Value;
            [app.STAGE4FILTERCOEF_A,app.STAGE4FILTERCOEF_B] = app.CalculateBellFilter(8000,gain,1);
        end
        
        function ComputeStageFive(app)
            gain = app.FifthStageSlider_16KHz.Value;
            [app.STAGE5FILTERCOEF_A,app.STAGE5FILTERCOEF_B] = app.CalculateBellFilter(16000,gain,1);
        end
        
        function ComputeOutput(app)
            app.ComputeStageOne();
            app.FilteredAudioContentStageOne = filter(app.STAGE1FILTERCOEF_B,app.STAGE1FILTERCOEF_A,app.AudioContent);

            app.ComputeStageTwo();
            app.FilteredAudioContentStageTwo = filter(app.STAGE2FILTERCOEF_B,app.STAGE2FILTERCOEF_A,app.FilteredAudioContentStageOne);

            app.ComputeStageThree();
            app.FilteredAudioContentStageThree = filter(app.STAGE3FILTERCOEF_B,app.STAGE3FILTERCOEF_A,app.FilteredAudioContentStageTwo);

            app.ComputeStageFour();
            app.FilteredAudioContentStageFour = filter(app.STAGE4FILTERCOEF_B,app.STAGE4FILTERCOEF_A,app.FilteredAudioContentStageThree);

            app.ComputeStageFive();
            app.FilteredAudioContentStageFive = filter(app.STAGE5FILTERCOEF_B,app.STAGE5FILTERCOEF_A,app.FilteredAudioContentStageFour);
            
            app.FilteredAudioContent = app.FilteredAudioContentStageFive;
            %app.UpdatePlot();
        end
        

        function UpdateOutputContent(app)
            app.ComputeOutput();
            pause(0.05);
            app.FetchCurrentSample();
            stop(app.player);
            app.CreateAudioPlayer(app.FilteredAudioContent);
            play(app.player,app.CurrentSongSample);
           
        end
        
        function UpdatePlot(app)
            plot(app.UIAxes,app.FilteredAudioContent,'r:');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %[filename,path] = uigetfile({"*.mp3";"*.wav";"*.m4a";"*.flac";"*.aac";},"Choose a song");
            app.LoadSong();
            %app.ApplyFilterToData();
            app.CreateAudioPlayer(app.AudioContent);
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            play(app.player)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player)
        end

        % Value changed function: FirstStageSlider_125HZ
        function FirstStageSlider_125HZValueChanged(app, event)
            app.SliderChangeHandle(app.FirstStageSlider_125HZ.Tag);
            
        end

        % Value changed function: SecondStageSlider_500Hz
        function SecondStageSlider_500HzValueChanged(app, event)
            app.SliderChangeHandle(app.SecondStageSlider_500Hz.Tag);
            
        end

        % Value changed function: ThirdStageSlider_4KHz
        function ThirdStageSlider_4KHzValueChanged(app, event)
            app.SliderChangeHandle(app.ThirdStageSlider_4KHz.Tag);
            
        end

        % Value changed function: FourthStageSlider_8KHz
        function FourthStageSlider_8KHzValueChanged(app, event)
            app.SliderChangeHandle(app.FourthStageSlider_8KHz.Tag);
        end

        % Value changed function: FifthStageSlider_16KHz
        function FifthStageSlider_16KHzValueChanged(app, event)
            app.SliderChangeHandle(app.FifthStageSlider_16KHz.Tag);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Frequency response')
            xlabel(app.UIAxes, 'Frequency (Hz) ')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 125 250 500 750 1000 2000 3000 4000 5000 6000 7000 8000 9000 10000 16000];
            app.UIAxes.XTickLabel = {'0'; '125'; '500'; '4000'; '8000'; '16000'};
            app.UIAxes.YTick = [-20 -10 0 10 20];
            app.UIAxes.YTickLabel = {'-20'; '-10'; '0'; '10'; '20'};
            app.FirstStageSlider_125HZ = uislider(app.GridLayout);
            app.FirstStageSlider_125HZ.Limits = [-20 20];
            app.FirstStageSlider_125HZ.MajorTicks = [-20 -10 0 10 20];
            app.FirstStageSlider_125HZ.ValueChangedFcn = createCallbackFcn(app, @FirstStageSlider_125HZValueChanged, true);
            app.FirstStageSlider_125HZ.Tag = 'Stage1';
            app.SecondStageSlider_500Hz = uislider(app.GridLayout);
            app.SecondStageSlider_500Hz.Limits = [-20 20];
            app.SecondStageSlider_500Hz.MajorTicks = [-20 -10 0 10 20];
            app.SecondStageSlider_500Hz.ValueChangedFcn = createCallbackFcn(app, @SecondStageSlider_500HzValueChanged, true);
            app.SecondStageSlider_500Hz.Tag = 'Stage2';
            app.ThirdStageSlider_4KHz = uislider(app.GridLayout);
            app.ThirdStageSlider_4KHz.Limits = [-20 20];
            app.ThirdStageSlider_4KHz.MajorTicks = [-20 -10 0 10 20];
            app.ThirdStageSlider_4KHz.ValueChangedFcn = createCallbackFcn(app, @ThirdStageSlider_4KHzValueChanged, true);
            app.ThirdStageSlider_4KHz.Tag = 'Stage3';
            app.FourthStageSlider_8KHz = uislider(app.GridLayout);
            app.FourthStageSlider_8KHz.Limits = [-20 20];
            app.FourthStageSlider_8KHz.MajorTicks = [-20 -10 0 10 20];
            app.FourthStageSlider_8KHz.ValueChangedFcn = createCallbackFcn(app, @FourthStageSlider_8KHzValueChanged, true);
            app.FourthStageSlider_8KHz.Tag = 'Stage4';
            app.FifthStageSlider_16KHz = uislider(app.GridLayout);
            app.FifthStageSlider_16KHz.Limits = [-20 20];
            app.FifthStageSlider_16KHz.MajorTicks = [-20 -10 0 10 20];
            app.FifthStageSlider_16KHz.ValueChangedFcn = createCallbackFcn(app, @FifthStageSlider_16KHzValueChanged, true);
            app.FifthStageSlider_16KHz.Tag = 'Stage5';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = {'Load'; ''};
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = {'Play'; ''};
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.HzLabel = uilabel(app.GridLayout);
            app.HzLabel.Text = '125 Hz';
            app.HzLabel_2 = uilabel(app.GridLayout);
            app.HzLabel_2.Text = '500 Hz';
            app.kHzLabel = uilabel(app.GridLayout);
            app.kHzLabel.Text = '4 kHz';
            app.kHzLabel_2 = uilabel(app.GridLayout);
            app.kHzLabel_2.Text = '8 kHz';
            app.kHzLabel_3 = uilabel(app.GridLayout);
            app.kHzLabel_3.Text = '16 kHz';
            app.ResumeButton = uibutton(app.GridLayout, 'push');
            app.ResumeButton.Text = 'Resume';
            app.SongLabel = uilabel(app.GridLayout);
            app.SongLabel.Text = 'Song:';
            app.CurrentSongName = uilabel(app.GridLayout);
            app.CurrentSongName.Text = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end