classdef prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Label                    matlab.ui.control.Label
        ParallelCheckBox         matlab.ui.control.CheckBox
        ParallelTLabel           matlab.ui.control.Label
        CascadeLabel             matlab.ui.control.Label
        CascadeCheckBox          matlab.ui.control.CheckBox
        QLabel                   matlab.ui.control.Label
        QFactorValue             matlab.ui.control.NumericEditField
        QFactorSlider            matlab.ui.control.Slider
        PlotButton               matlab.ui.control.Button
        FifthStageValue          matlab.ui.control.NumericEditField
        FourthStageValue         matlab.ui.control.NumericEditField
        ThirdStageValue          matlab.ui.control.NumericEditField
        SecondStageValue         matlab.ui.control.NumericEditField
        FirstStageValue          matlab.ui.control.NumericEditField
        CurrentSongName          matlab.ui.control.Label
        SongLabel                matlab.ui.control.Label
        PauseButton              matlab.ui.control.Button
        kHzLabel_3               matlab.ui.control.Label
        kHzLabel_2               matlab.ui.control.Label
        kHzLabel                 matlab.ui.control.Label
        HzLabel_2                matlab.ui.control.Label
        HzLabel                  matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        PlayButton               matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        FifthStageSlider_16KHz   matlab.ui.control.Slider
        FourthStageSlider_8KHz   matlab.ui.control.Slider
        ThirdStageSlider_4KHz    matlab.ui.control.Slider
        SecondStageSlider_500Hz  matlab.ui.control.Slider
        FirstStageSlider_125HZ   matlab.ui.control.Slider
    end

    
    properties (Access = private)
        filename % Description
        path % Description
        player
        Fs = 96000;
        Q=2;
        AudioContent
        FilteredAudioContent
        CurrentSongSample
        STAGE_125HZ
        STAGE_500HZ
        STAGE_4KHZ
        STAGE_8KHZ
        STAGE_16KHZ


        STAGE1FILTERCOEF_A
        STAGE1FILTERCOEF_B

        STAGE2FILTERCOEF_A
        STAGE2FILTERCOEF_B

        STAGE3FILTERCOEF_A
        STAGE3FILTERCOEF_B

        STAGE4FILTERCOEF_A
        STAGE4FILTERCOEF_B

        STAGE5FILTERCOEF_A
        STAGE5FILTERCOEF_B


        FilteredAudioContentStageOne
        FilteredAudioContentStageTwo
        FilteredAudioContentStageThree
        FilteredAudioContentStageFour
        FilteredAudioContentStageFive

        
        FilterStructure
    
        HasFileBeenLoaded
        HasPlaybackStarted
        HasPauseButtonBeenPressed

        ParallelSelected
        CascadeSelected = 1
    end
    
    methods (Access = private)
        
        function [b1,a1] = CalculateBellFilter(app,CentreFrequency,boost,Q)
            %Values from slider are reversed
            G = 10^(-boost/20);
            k = 3*(G-1)/(G+1);
            Wo = 2*pi*CentreFrequency;
            B = [1 (3+k)*Wo/app.Q Wo^2]; % Specify analogue transfer func.
            A = [1 (3-k)*Wo/app.Q Wo^2];
            [b1, a1] = bilinear(B, A, app.Fs,CentreFrequency); % calculate BZT filter coefficient
            
        end
        
        function ParseAudioData(app)
            [app.AudioContent,app.Fs] = audioread(app.filename);
            app.HasFileBeenLoaded = 1;
        end
        
        function LoadSong(app)
            [app.filename,app.path] = uigetfile({"*.wav";"*.mp3";});
            if app.filename == 0
                return;
            end
            app.UpdateSongLabel();
            app.PlayButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.PlotButton.Enable = "on"
            app.ParseAudioData();
        end
        
        function CreateAudioPlayer(app,StartingSample)
            %Creates an audioplayer object
            app.player = audioplayer(StartingSample,app.Fs); 
        end
        
        function ApplyFilterToData(app)
            [gain] = app.GetSlider250Hz();
            [b1,a1] = app.CalculateBellFilter(gain);
            app.FilteredAudioContent = filter(b1,a1,app.AudioContent);
        end
       
        
        function EventHandlers(app,SliderID)
            if app.HasFileBeenLoaded == 1
                if app.HasPlaybackStarted == 1
                switch SliderID
                    case app.FirstStageSlider_125HZ.Tag
                        app.UpdateOutputContent();
                    case app.SecondStageSlider_500Hz.Tag
                        app.UpdateOutputContent();
                    case app.ThirdStageSlider_4KHz.Tag
                        app.UpdateOutputContent();
                    case app.FourthStageSlider_8KHz.Tag
                        app.UpdateOutputContent();
                    case app.FifthStageSlider_16KHz.Tag
                        app.UpdateOutputContent();
                    case app.QFactorSlider.Tag
                        app.UpdateQFactor();
                        app.UpdateOutputContent();
                    case app.ParallelCheckBox.Tag
                        app.UpdateOutputContent();
                    case app.CascadeCheckBox.Tag
                        app.UpdateOutputContent()
                   end
                end
            else
                return;
            end
        end


        function FetchCurrentSample(app)
            app.CurrentSongSample = get(app.player,'CurrentSample');
        end
        function UpdateSongLabel(app)
            app.CurrentSongName.Text = app.filename;
            app.SongLabel.Visible = 'on';
        end
        
        
        function ComputeStageOne(app)
            gain = app.FirstStageSlider_125HZ.Value;
            app.FirstStageValue.Value = gain;
            [app.STAGE1FILTERCOEF_A,app.STAGE1FILTERCOEF_B] = app.CalculateBellFilter(125,gain,app.Q);
            app.STAGE_125HZ=dfilt.df1(app.STAGE1FILTERCOEF_B,app.STAGE1FILTERCOEF_A);
        end
        
        function ComputeStageTwo(app)
            gain = app.SecondStageSlider_500Hz.Value;
            app.SecondStageValue.Value = gain;
            [app.STAGE2FILTERCOEF_A,app.STAGE2FILTERCOEF_B] = app.CalculateBellFilter(500,gain,app.Q);
            app.STAGE_500HZ=dfilt.df1(app.STAGE2FILTERCOEF_B,app.STAGE2FILTERCOEF_A);
        end
        
        function ComputeStageThree(app)
            gain = app.ThirdStageSlider_4KHz.Value;
            app.ThirdStageValue.Value = gain;
            [app.STAGE3FILTERCOEF_A,app.STAGE3FILTERCOEF_B] = app.CalculateBellFilter(4000,gain,app.Q);
            app.STAGE_4KHZ = dfilt.df1(app.STAGE3FILTERCOEF_B,app.STAGE3FILTERCOEF_A);
        end
        
        function ComputeStageFour(app)
            gain = app.FourthStageSlider_8KHz.Value;
            app.FourthStageValue.Value = gain;
            [app.STAGE4FILTERCOEF_A,app.STAGE4FILTERCOEF_B] = app.CalculateBellFilter(8000,gain,app.Q);
            app.STAGE_8KHZ = dfilt.df1(app.STAGE4FILTERCOEF_B,app.STAGE4FILTERCOEF_A);
        end
        
        function ComputeStageFive(app)
            gain = app.FifthStageSlider_16KHz.Value;
            app.FifthStageValue.Value = gain;
            [app.STAGE5FILTERCOEF_A,app.STAGE5FILTERCOEF_B] = app.CalculateBellFilter(16000,gain,app.Q);
            app.STAGE_16KHZ = dfilt.df1(app.STAGE5FILTERCOEF_B,app.STAGE5FILTERCOEF_A);
        end
        
        function ComputeOutput(app)
            app.ComputeStageOne();
            app.ComputeStageTwo();
            app.ComputeStageThree();
            app.ComputeStageFour();
            app.ComputeStageFive();

            if app.CascadeSelected == 1
                app.FilterStructure = dfilt.cascade(app.STAGE_125HZ,app.STAGE_500HZ,app.STAGE_4KHZ,app.STAGE_8KHZ,app.STAGE_16KHZ);
            else
                app.FilterStructure = dfilt.parallel(app.STAGE_125HZ,app.STAGE_500HZ,app.STAGE_4KHZ,app.STAGE_8KHZ,app.STAGE_16KHZ);
            end
            app.FilteredAudioContent = filter(app.FilterStructure,app.AudioContent);
        end
        

        function UpdateOutputContent(app)
            app.ComputeOutput();
            if app.HasPauseButtonBeenPressed == 1
                return
            else
                pause(0.01);
                app.FetchCurrentSample();
                stop(app.player);
                app.CreateAudioPlayer(app.FilteredAudioContent);
                play(app.player,app.CurrentSongSample);
            end
        end
        
        function UpdatePlot(app)
            plot(app.UIAxes,app.FilteredAudioContentStageFive,'r:');
        end
        
        function UpdateQFactor(app)
            app.Q = round(app.QFactorSlider.Value);
            app.QFactorValue.Value = app.Q;
        end
        
        function CheckBoxHandler(app,SelectedCheckBox)

            switch SelectedCheckBox
                case app.ParallelCheckBox.Tag
                    app.CascadeCheckBox.Value = 0;
                    app.ParallelCheckBox.Value = 1;
                    app.CascadeSelected = 0;
                    app.ParallelSelected = 1;
                    app.EventHandlers(app.ParallelCheckBox.Tag);
                case app.CascadeCheckBox.Tag
                    app.CascadeCheckBox.Value = 1;
                    app.ParallelCheckBox.Value = 0;
                    app.CascadeSelected = 1;
                    app.ParallelSelected = 0;
                    app.EventHandlers(app.CascadeCheckBox.Tag);
            end   
    end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %[filename,path] = uigetfile({"*.mp3";"*.wav";"*.m4a";"*.flac";"*.aac";},"Choose a song");
            app.LoadSong();
            %app.ApplyFilterToData();
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.HasPlaybackStarted = 1;
            app.CreateAudioPlayer(app.AudioContent);
            play(app.player)
            app.PauseButton.Text ="Pause"
            app.PauseButton.Enable = 'on'
            app.HasPauseButtonBeenPressed = 0;
            app.ComputeOutput();
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player)
        end

        % Value changed function: FirstStageSlider_125HZ
        function FirstStageSlider_125HZValueChanged(app, event)
            app.FirstStageValue.Value = app.FirstStageSlider_125HZ.Value;
            app.EventHandlers(app.FirstStageSlider_125HZ.Tag);
        end

        % Value changed function: SecondStageSlider_500Hz
        function SecondStageSlider_500HzValueChanged(app, event)
            app.SecondStageValue.Value = app.SecondStageSlider_500Hz.Value;
            app.EventHandlers(app.SecondStageSlider_500Hz.Tag);
        end

        % Value changed function: ThirdStageSlider_4KHz
        function ThirdStageSlider_4KHzValueChanged(app, event)
            app.ThirdStageValue.Value = app.ThirdStageSlider_4KHz.Value;
            app.EventHandlers(app.ThirdStageSlider_4KHz.Tag);
        end

        % Value changed function: FourthStageSlider_8KHz
        function FourthStageSlider_8KHzValueChanged(app, event)
            app.FourthStageValue.Value = app.FourthStageSlider_8KHz.Value;
            app.EventHandlers(app.FourthStageSlider_8KHz.Tag);
        end

        % Value changed function: FifthStageSlider_16KHz
        function FifthStageSlider_16KHzValueChanged(app, event)
            app.FifthStageValue.Value = app.FifthStageSlider_16KHz.Value;
            app.EventHandlers(app.FifthStageSlider_16KHz.Tag);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            freqz(app.FilterStructure,2^15,app.Fs);
        end

        % Value changed function: QFactorSlider
        function QFactorSliderValueChanged(app, event)
            app.QFactorValue.Value = round(app.QFactorSlider.Value);
            app.EventHandlers(app.QFactorSlider.Tag);
         
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            
            if app.HasPauseButtonBeenPressed == 1
            app.PauseButton.Text = "Pause"
            app.HasPauseButtonBeenPressed = 0;
            resume(app.player);
            else
            pause(app.player);
            app.HasPauseButtonBeenPressed = 1;
            app.PauseButton.Text = "Resume"
            end

        end

        % Value changed function: ParallelCheckBox
        function ParallelCheckBoxValueChanged(app, event)
            value = app.ParallelCheckBox.Value;
            app.CheckBoxHandler(app.ParallelCheckBox.Tag);
        end

        % Value changed function: CascadeCheckBox
        function CascadeCheckBoxValueChanged(app, event)
            value = app.CascadeCheckBox.Value;
            app.CheckBoxHandler(app.CascadeCheckBox.Tag);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FirstStageSlider_125HZ = uislider(app.GridLayout);
            app.FirstStageSlider_125HZ.Limits = [-20 20];
            app.FirstStageSlider_125HZ.MajorTicks = [-20 -10 0 10 20];
            app.FirstStageSlider_125HZ.ValueChangedFcn = createCallbackFcn(app, @FirstStageSlider_125HZValueChanged, true);
            app.FirstStageSlider_125HZ.Tag = 'Stage1';
            app.SecondStageSlider_500Hz = uislider(app.GridLayout);
            app.SecondStageSlider_500Hz.Limits = [-20 20];
            app.SecondStageSlider_500Hz.MajorTicks = [-20 -10 0 10 20];
            app.SecondStageSlider_500Hz.ValueChangedFcn = createCallbackFcn(app, @SecondStageSlider_500HzValueChanged, true);
            app.SecondStageSlider_500Hz.Tag = 'Stage2';
            app.ThirdStageSlider_4KHz = uislider(app.GridLayout);
            app.ThirdStageSlider_4KHz.Limits = [-20 20];
            app.ThirdStageSlider_4KHz.MajorTicks = [-20 -10 0 10 20];
            app.ThirdStageSlider_4KHz.ValueChangedFcn = createCallbackFcn(app, @ThirdStageSlider_4KHzValueChanged, true);
            app.ThirdStageSlider_4KHz.Tag = 'Stage3';
            app.FourthStageSlider_8KHz = uislider(app.GridLayout);
            app.FourthStageSlider_8KHz.Limits = [-20 20];
            app.FourthStageSlider_8KHz.MajorTicks = [-20 -10 0 10 20];
            app.FourthStageSlider_8KHz.ValueChangedFcn = createCallbackFcn(app, @FourthStageSlider_8KHzValueChanged, true);
            app.FourthStageSlider_8KHz.Tag = 'Stage4';
            app.FifthStageSlider_16KHz = uislider(app.GridLayout);
            app.FifthStageSlider_16KHz.Limits = [-20 20];
            app.FifthStageSlider_16KHz.MajorTicks = [-20 -10 0 10 20];
            app.FifthStageSlider_16KHz.ValueChangedFcn = createCallbackFcn(app, @FifthStageSlider_16KHzValueChanged, true);
            app.FifthStageSlider_16KHz.Tag = 'Stage5';
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = {'Load'; ''};
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = {'Play'; ''};
            app.StopButton = uibutton(app.GridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = {'Stop'; ''};
            app.HzLabel = uilabel(app.GridLayout);
            app.HzLabel.Text = '125 Hz';
            app.HzLabel_2 = uilabel(app.GridLayout);
            app.HzLabel_2.Text = '500 Hz';
            app.kHzLabel = uilabel(app.GridLayout);
            app.kHzLabel.Text = '4 kHz';
            app.kHzLabel_2 = uilabel(app.GridLayout);
            app.kHzLabel_2.Text = '8 kHz';
            app.kHzLabel_3 = uilabel(app.GridLayout);
            app.kHzLabel_3.Text = '16 kHz';
            app.PauseButton = uibutton(app.GridLayout, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.SongLabel = uilabel(app.GridLayout);
            app.SongLabel.Visible = 'off';
            app.SongLabel.Text = 'Song';
            app.CurrentSongName = uilabel(app.GridLayout);
            app.CurrentSongName.Text = '';
            app.FirstStageValue = uieditfield(app.GridLayout, 'numeric');
            app.FirstStageValue.Editable = 'off';
            app.SecondStageValue = uieditfield(app.GridLayout, 'numeric');
            app.SecondStageValue.Editable = 'off';
            app.ThirdStageValue = uieditfield(app.GridLayout, 'numeric');
            app.ThirdStageValue.Editable = 'off';
            app.FourthStageValue = uieditfield(app.GridLayout, 'numeric');
            app.FourthStageValue.Editable = 'off';
            app.FifthStageValue = uieditfield(app.GridLayout, 'numeric');
            app.FifthStageValue.Editable = 'off';
            app.PlotButton = uibutton(app.GridLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.QFactorSlider = uislider(app.GridLayout);
            app.QFactorSlider.Limits = [1 20];
            app.QFactorSlider.MajorTicks = [1 5 10 15 20];
            app.QFactorSlider.ValueChangedFcn = createCallbackFcn(app, @QFactorSliderValueChanged, true);
            app.QFactorSlider.MinorTicks = [2 3 4 6 7 8 9 11 12 13 14 16 17 18 19];
            app.QFactorSlider.Tag = 'NewQValue';
            app.QFactorSlider.Value = 2;
            app.QFactorValue = uieditfield(app.GridLayout, 'numeric');
            app.QFactorValue.Editable = 'off';
            app.QFactorValue.Value = 2;
            app.QLabel = uilabel(app.GridLayout);
            app.QLabel.Text = 'Q';
            app.CascadeCheckBox = uicheckbox(app.GridLayout);
            app.CascadeCheckBox.ValueChangedFcn = createCallbackFcn(app, @CascadeCheckBoxValueChanged, true);
            app.CascadeCheckBox.Tag = 'CascadeSelected';
            app.CascadeCheckBox.Text = '';
            app.CascadeCheckBox.Value = true;
            app.CascadeLabel = uilabel(app.GridLayout);
            app.CascadeLabel.Text = 'Cascade';
            app.ParallelTLabel = uilabel(app.GridLayout);
            app.ParallelTLabel.Enable = 'off';
            app.ParallelTLabel.Visible = 'off';
            app.ParallelTLabel.Text = 'Parallel(T)';
            app.ParallelCheckBox = uicheckbox(app.GridLayout);
            app.ParallelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParallelCheckBoxValueChanged, true);
            app.ParallelCheckBox.Tag = 'ParallelSelected';
            app.ParallelCheckBox.Enable = 'off';
            app.ParallelCheckBox.Visible = 'off';
            app.ParallelCheckBox.Text = {''; ''};
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end